classdef PressureRun_APPL240_FinalProject0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        StartGameButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

     properties (Access = private)
      ad3
        ain
        char
        char_x
        char_y
        max_speed = 5
        obstacles
        goal
        currentPlayer = 1
        playerColors = {'r', 'b'}
        playerTimes = [NaN, NaN]
        startTime
        crashCounts = [0, 0]
        maxAttempts = 1
        playerFinalX = [0, 0]
        playerFinished = [false, false]
     end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
 daqreset;
            setupAD3PowerSupply(3);
            app.ad3 = daq("ni");
            app.ain = setupAD3AnalogInput(2);

            app.StartGameButton.Visible = 'off';

            app.UIAxes.Color = [0.6, 0.8, 1.0]; % Carolina Blue
            app.UIAxes.XColor = [0 0 0];
            app.UIAxes.YColor = [0 0 0];

            app.obstacles = [
                20 0 3 70;
                40 30 3 70;
                60 0 3 70;
                80 30 3 70;
                0 0 100 3;
                0 97 100 3;
                0 0 3 100;
                97 0 3 100
            ];

            app.currentPlayer = 1;
            setupPlayer(app);
            runGame(app);
        end

        function setupPlayer(app)
            cla(app.UIAxes);
            axis(app.UIAxes, [0 100 0 100]);
            xlabel(app.UIAxes, 'Distance', 'Color', 'k');
            ylabel(app.UIAxes, 'Height', 'Color', 'k');
            title(app.UIAxes, sprintf('Pressure Run - Player %d', app.currentPlayer), 'Color', 'k');
            hold(app.UIAxes, 'on');

            for i = 1:size(app.obstacles, 1)
                rectangle(app.UIAxes, ...
                    'Position', app.obstacles(i,:), ...
                    'FaceColor', [1 1 1], ...
                    'EdgeColor', 'w');
            end

            % Draw dark green finish dot
            plot(app.UIAxes, 95, 95, 'o', 'MarkerSize', 12, 'MarkerFaceColor', [0 0.5 0], 'MarkerEdgeColor', 'k');

            app.char_x = 5;
            app.char_y = 5;
            app.char = plot(app.UIAxes, app.char_x, app.char_y, ...
                'o', 'MarkerSize', 10, 'MarkerFaceColor', app.playerColors{app.currentPlayer});

            drawnow;
            app.startTime = tic;
        end

        function runGame(app)
            while true
                data = read(app.ain);
                fsr_val = data.AD3_0_ai0;
                accel_y_val = data.AD3_0_ai1;

                if fsr_val > 0.2
                    speed = (fsr_val / 3.0) * app.max_speed;
                    app.char_x = app.char_x + speed * 0.4;
                end

                if accel_y_val > 1.4
                    app.char_y = app.char_y + 2.2;
                else
                    app.char_y = app.char_y - 2.2;
                end

                app.char_x = max(0, min(100, app.char_x));
                app.char_y = max(0, min(100, app.char_y));

                set(app.char, 'XData', app.char_x, 'YData', app.char_y);
                drawnow;

                for i = 1:size(app.obstacles, 1)
                    obs = app.obstacles(i,:);
                    if app.char_x >= obs(1) && app.char_x <= (obs(1)+obs(3)) && ...
                       app.char_y >= obs(2) && app.char_y <= (obs(2)+obs(4))
                        app.crashCounts(app.currentPlayer) = app.crashCounts(app.currentPlayer) + 1;
                        app.playerFinalX(app.currentPlayer) = app.char_x;
                        app.playerFinished(app.currentPlayer) = false;
                        uialert(app.UIFigure, sprintf('💥 Player %d crashed!', app.currentPlayer), 'Collision');
                        moveToNextPlayer(app);
                        return;
                    end
                end

                if sqrt((app.char_x - 95)^2 + (app.char_y - 95)^2) < 5
                    elapsedTime = toc(app.startTime);
                    app.playerTimes(app.currentPlayer) = elapsedTime;
                    app.playerFinalX(app.currentPlayer) = 100;
                    app.playerFinished(app.currentPlayer) = true;
                    uialert(app.UIFigure, sprintf('🎉 Player %d finished in %.2f seconds!', app.currentPlayer, elapsedTime), 'Victory');
                    moveToNextPlayer(app);
                    return;
                end

                pause(0.03);
            end
        end

        function moveToNextPlayer(app)
            if app.currentPlayer == 1
                selection = uiconfirm(app.UIFigure, ...
                    'Player 2, press OK when ready to start!', ...
                    'Next Turn', 'Options', {'OK'}, 'DefaultOption', 1);

                if strcmp(selection, 'OK')
                    daqreset;
                    setupAD3PowerSupply(3);
                    app.ad3 = daq("ni");
                    app.ain = setupAD3AnalogInput(2);

                    app.currentPlayer = 2;
                    setupPlayer(app);
                    runGame(app);
                end
            else
                determineWinner(app);
            end
        end

        function determineWinner(app)
            % Determine winner
            if all(app.playerFinished)
                if app.playerTimes(1) < app.playerTimes(2)
                    winnerText = sprintf('🏆 Player 1 wins! (%.2f s vs %.2f s)', app.playerTimes(1), app.playerTimes(2));
                elseif app.playerTimes(2) < app.playerTimes(1)
                    winnerText = sprintf('🏆 Player 2 wins! (%.2f s vs %.2f s)', app.playerTimes(2), app.playerTimes(1));
                else
                    winnerText = '🤝 It''s a tie!';
                end
            elseif app.playerFinished(1)
                winnerText = '🏆 Player 1 wins by finishing!';
            elseif app.playerFinished(2)
                winnerText = '🏆 Player 2 wins by finishing!';
            else
                if app.playerFinalX(1) > app.playerFinalX(2)
                    winnerText = sprintf('🏁 Player 1 went farther! (%.2f vs %.2f)', app.playerFinalX(1), app.playerFinalX(2));
                elseif app.playerFinalX(2) > app.playerFinalX(1)
                    winnerText = sprintf('🏁 Player 2 went farther! (%.2f vs %.2f)', app.playerFinalX(2), app.playerFinalX(1));
                else
                    winnerText = '🤝 It''s a tie!';
                end
            end

            % 🎉 Final Celebration
            cla(app.UIAxes);
            axis(app.UIAxes, [0 100 0 100]);
            app.UIAxes.Color = [1 1 1];
            hold(app.UIAxes, 'on');

            % Winner text
            text(50, 90, '🎊 CONGRATULATIONS! 🎊', 'Parent', app.UIAxes, ...
                'HorizontalAlignment', 'center', 'FontSize', 22, 'FontWeight', 'bold', 'Color', 'k');
            text(50, 80, winnerText, 'Parent', app.UIAxes, ...
                'HorizontalAlignment', 'center', 'FontSize', 18, 'Color', 'k');

            % Confetti falling AROUND text (not covering it)
            for y = 100:-5:10
                xSpread = [randi([5,40],5,1); randi([60,95],5,1)];
                scatter(app.UIAxes, xSpread, y*ones(10,1), randi([30,70],10,1), rand(10,3), 'filled');
                pause(0.02);
            end

            hold(app.UIAxes, 'off');

            app.StartGameButton.Visible = 'on';
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PressureRun_APPL240_FinalProject0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end